classdef FilteredPhotos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    methods (Access = private)
        
        function ShowFilteredImages(app)
            if isempty(app.FilteredImages)
                uialert(app.UIFigure, 'No filtered photos found.', 'Hata');
                return;
            end

            % Yeni bir figür oluştur
            resultFigure = uifigure('Name', 'Filtered Photos', 'Position', [100 100 1200 800]);
            
            numImages = length(app.FilteredImages);
            
            % Izgara düzeni için satır ve sütun sayısını belirle
            % Örneğin, 6 fotoğraf için 2x3 veya 3x2 ideal olabilir
            if numImages <= 3
                numRows = 1;
                numCols = numImages;
            elseif numImages <= 6
                numRows = 2;
                numCols = ceil(numImages / 2);
            else % Daha fazla fotoğraf için
                numRows = ceil(sqrt(numImages));
                numCols = ceil(numImages / numRows);
            end

            % Tile layout kullanarak eksenleri düzenle
            tiledLayout = uigridlayout(resultFigure, [numRows numCols]);
            tiledLayout.Padding = 'compact';
            tiledLayout.RowHeight = {'1x', '1x', '1x'}; % Esnek satır yükseklikleri
            tiledLayout.ColumnWidth = {'1x', '1x', '1x'}; % Esnek sütun genişlikleri

            for i = 1:numImages
                % Her fotoğraf için yeni bir eksen oluştur
                ax = uiaxes(tiledLayout);
                ax.Layout.Row = ceil(i / numCols);
                ax.Layout.Column = mod(i - 1, numCols) + 1;
                
                % Fotoğrafı göster
                imshow(app.FilteredImages{i}, 'Parent', ax);
                title(ax, app.FilterNames{i});
                
                % Eksenlerin görünümünü temizle
                ax.XAxis.Visible = 'off';
                ax.YAxis.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilteredPhotos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end