classdef PhotoFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhotoFiltererUIFigure  matlab.ui.Figure
        Image2                 matlab.ui.control.Image
        SaltPaperfield         matlab.ui.control.NumericEditField
        HighBoostfield         matlab.ui.control.NumericEditField
        OTSUSegmentationLabel  matlab.ui.control.Label
        OTSUCheckBox           matlab.ui.control.CheckBox
        CornerDetectionLabel   matlab.ui.control.Label
        HarrisCheckBox         matlab.ui.control.CheckBox
        RobertsCheckBox        matlab.ui.control.CheckBox
        SobelCheckBox          matlab.ui.control.CheckBox
        EdgeDetectionLabel     matlab.ui.control.Label
        CannyCheckBox          matlab.ui.control.CheckBox
        PrewittCheckBox        matlab.ui.control.CheckBox
        MorphologyLabel        matlab.ui.control.Label
        MinCheckBox            matlab.ui.control.CheckBox
        MaxCheckBox            matlab.ui.control.CheckBox
        MedianCheckBox         matlab.ui.control.CheckBox
        SpatialFiltersLabel    matlab.ui.control.Label
        HighBoostCheckBox      matlab.ui.control.CheckBox
        WeightedCheckBox       matlab.ui.control.CheckBox
        AverageCheckBox        matlab.ui.control.CheckBox
        NoiseLabel             matlab.ui.control.Label
        GaussianNoiseCheckBox  matlab.ui.control.CheckBox
        ImpulseCheckBox        matlab.ui.control.CheckBox
        SaltPepperCheckBox     matlab.ui.control.CheckBox
        Nthfield               matlab.ui.control.NumericEditField
        Logfield               matlab.ui.control.NumericEditField
        IntensityTransformationFunctionsLabel  matlab.ui.control.Label
        DilationCheckBox       matlab.ui.control.CheckBox
        ErasionCheckBox        matlab.ui.control.CheckBox
        DerivativeCheckBox     matlab.ui.control.CheckBox
        nthpowerrootCheckBox   matlab.ui.control.CheckBox
        LogReverseLogCheckBox  matlab.ui.control.CheckBox
        NegativeCheckBox       matlab.ui.control.CheckBox
        ProgressBarGauge       matlab.ui.control.SemicircularGauge
        ProgressBarLabel       matlab.ui.control.Label
        ApplytheFiltersButton  matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        UploadaPhotoButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        OrijinalFotograf % Yüklenen orijinal fotoğrafı saklamak için
        FilteredImages   % Filtrelenmiş fotoğrafları saklamak için
        FilterNames      % Filtre isimlerini saklamak için
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadaPhotoButton
        function UploadaPhotoButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Bir Fotoğraf Seçin');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                % Kullanıcı fotoğraf seçmekten vazgeçti
                return;
            end
            
            % Orijinal fotoğrafı oku ve sakla
            app.OrijinalFotograf = imread(fullfile(pathname, filename));
            
            % Yüklenen fotoğrafı Image bileşeninde göster
            % Image bileşeni için 'ImageSource' özelliğini kullanın
            app.Image.ImageSource = app.OrijinalFotograf;
            
            % Filtre uygulama butonunu etkinleştir
            app.ApplytheFiltersButton.Enable = 'on';
            
            % İlerleme çubuğunu sıfırla ve gizle
            app.ProgressBarGauge.Value = 0;
            app.ProgressBarGauge.Visible = 'off';
            app.ProgressBarLabel.Visible = 'off'; % İlerleme çubuğu etiketini de gizle
            
            % Tüm filtre CheckBox'larını etkinleştir
            app.OTSUCheckBox.Enable = 'on';
            app.HarrisCheckBox.Enable = 'on';
            app.RobertsCheckBox.Enable = 'on';
            app.SobelCheckBox.Enable = 'on';
            app.CannyCheckBox.Enable = 'on';
            app.PrewittCheckBox.Enable = 'on';
            app.MinCheckBox.Enable = 'on';
            app.MaxCheckBox.Enable = 'on';
            app.MedianCheckBox.Enable = 'on';
            app.HighBoostCheckBox.Enable = 'on';
            app.WeightedCheckBox.Enable = 'on';
            app.AverageCheckBox.Enable = 'on';
            app.GaussianNoiseCheckBox.Enable = 'on';
            app.ImpulseCheckBox.Enable = 'on';
            app.SaltPepperCheckBox.Enable = 'on';
            app.DilationCheckBox.Enable = 'on';
            app.ErasionCheckBox.Enable = 'on';
            app.DerivativeCheckBox.Enable = 'on';
            app.nthpowerrootCheckBox.Enable = 'on';
            app.LogReverseLogCheckBox.Enable = 'on';
            app.NegativeCheckBox.Enable = 'on';
            
            % EditField'ları da etkinleştirebilirsiniz, eğer filtrelerle ilgili parametreler alıyorlarsa
            app.Logfield.Enable = 'on';
            app.Nthfield.Enable = 'on';
        end

        % Button pushed function: ApplytheFiltersButton
        function ApplytheFiltersButtonPushed(app, event)
            if isempty(app.OrijinalFotograf)
                uialert(app.PhotoFiltererUIFigure, 'Please Upload a Photo.', 'Uyarı');
                return;
            end
            
            % İlerleme çubuğunu ve etiketini görünür yap ve sıfırla
            app.ProgressBarGauge.Visible = 'on';
            app.ProgressBarLabel.Visible = 'on';
            app.ProgressBarGauge.Value = 0;
            app.ProgressBarLabel.Text = 'Applying Filters...';
            drawnow; % Arayüzü hemen güncelle
            
            app.FilteredImages = {}; % Filtrelenmiş fotoğrafları saklamak için boşalt
            app.FilterNames = {};    % Filtre isimlerini saklamak için boşalt
            
            % Orijinal fotoğrafı her zaman ilk olarak ekle
            app.FilteredImages{end+1} = app.OrijinalFotograf;
            app.FilterNames{end+1} = 'Original Photos';
            
            % Seçilen filtre sayısını hesapla
            numSelectedFilters = 0;
            if app.OTSUCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.HarrisCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.RobertsCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.SobelCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.CannyCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.PrewittCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.MinCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.MaxCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.MedianCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.HighBoostCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.WeightedCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.AverageCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.GaussianNoiseCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.ImpulseCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.SaltPepperCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.DilationCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.ErasionCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.DerivativeCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.nthpowerrootCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.LogReverseLogCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            if app.NegativeCheckBox.Value, numSelectedFilters = numSelectedFilters + 1; end
            
            if numSelectedFilters == 0
                uialert(app.PhotoFiltererUIFigure, 'Please pick filter.', 'Uyarı');
                app.ProgressBarGauge.Visible = 'off';
                app.ProgressBarLabel.Visible = 'off';
                return;
            end
            
            filterCount = 0;
            
            % Filtreleri Uygula ve İlerleme Çubuğunu Güncelle
            % OTSU Segmentasyon
            if app.OTSUCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'OTSU Segmentasyon Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Manuel OTSU threshold hesaplama
                level = app.manualOtsuThreshold(grayImg);
                
                % Manuel binarization
                binaryImg = grayImg > level;
                app.FilteredImages{end+1} = uint8(binaryImg * 255);
                app.FilterNames{end+1} = 'OTSU Segmentasyon';
            end
            
            % Köşe Algılama (Harris)
            if app.HarrisCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Harris Köşe Algılama Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Basitleştirilmiş Harris köşe algılama
                corners = app.manualHarrisCorners(grayImg);
                
                % Köşeleri görselleştir
                imgWithCorners = app.OrijinalFotograf;
                for i = 1:size(corners, 1)
                    x = corners(i, 1);
                    y = corners(i, 2);
                    % Kırmızı daire çiz
                    if x > 5 && x < size(imgWithCorners, 2) - 5 && y > 5 && y < size(imgWithCorners, 1) - 5
                        imgWithCorners(y-2:y+2, x-2:x+2, 1) = 255;
                        imgWithCorners(y-2:y+2, x-2:x+2, 2) = 0;
                        imgWithCorners(y-2:y+2, x-2:x+2, 3) = 0;
                    end
                end
                
                app.FilteredImages{end+1} = imgWithCorners;
                app.FilterNames{end+1} = 'Harris Köşe Algılama';
            end
            
            % Kenar Algılama - Roberts
            if app.RobertsCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Roberts Kenar Algılama Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Roberts operatörleri
                Gx = [1 0; 0 -1];
                Gy = [0 1; -1 0];
                
                % Konvolüsyon
                Ix = app.manualConv2D(double(grayImg), Gx);
                Iy = app.manualConv2D(double(grayImg), Gy);
                
                % Magnitude
                magnitude = sqrt(Ix.^2 + Iy.^2);
                
                % Threshold ve binary
                threshold = 0.1 * max(magnitude(:));
                edges = magnitude > threshold;
                
                app.FilteredImages{end+1} = uint8(edges * 255);
                app.FilterNames{end+1} = 'Roberts Kenar Algılama';
            end
            
            % Kenar Algılama - Sobel
            if app.SobelCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Sobel Kenar Algılama Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Sobel operatörleri
                Gx = [-1 0 1; -2 0 2; -1 0 1];
                Gy = [-1 -2 -1; 0 0 0; 1 2 1];
                
                % Konvolüsyon
                Ix = app.manualConv2D(double(grayImg), Gx);
                Iy = app.manualConv2D(double(grayImg), Gy);
                
                % Magnitude
                magnitude = sqrt(Ix.^2 + Iy.^2);
                
                % Threshold ve binary
                threshold = 0.1 * max(magnitude(:));
                edges = magnitude > threshold;
                
                app.FilteredImages{end+1} = uint8(edges * 255);
                app.FilterNames{end+1} = 'Sobel Kenar Algılama';
            end
            
            % Kenar Algılama - Canny (Basitleştirilmiş)
            if app.CannyCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Canny Kenar Algılama Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Basitleştirilmiş Canny (Gaussian blur + Sobel)
                % Gaussian blur
                gaussKernel = [1 2 1; 2 4 2; 1 2 1] / 16;
                blurred = app.manualConv2D(double(grayImg), gaussKernel);
                
                % Sobel
                Gx = [-1 0 1; -2 0 2; -1 0 1];
                Gy = [-1 -2 -1; 0 0 0; 1 2 1];
                
                Ix = app.manualConv2D(blurred, Gx);
                Iy = app.manualConv2D(blurred, Gy);
                
                magnitude = sqrt(Ix.^2 + Iy.^2);
                
                % Double threshold
                highThreshold = 0.15 * max(magnitude(:));
                lowThreshold = 0.05 * max(magnitude(:));
                
                edges = magnitude > highThreshold;
                
                app.FilteredImages{end+1} = uint8(edges * 255);
                app.FilterNames{end+1} = 'Canny Kenar Algılama';
            end
            
            % Kenar Algılama - Prewitt
            if app.PrewittCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Prewitt Kenar Algılama Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Prewitt operatörleri
                Gx = [-1 0 1; -1 0 1; -1 0 1];
                Gy = [-1 -1 -1; 0 0 0; 1 1 1];
                
                % Konvolüsyon
                Ix = app.manualConv2D(double(grayImg), Gx);
                Iy = app.manualConv2D(double(grayImg), Gy);
                
                % Magnitude
                magnitude = sqrt(Ix.^2 + Iy.^2);
                
                % Threshold ve binary
                threshold = 0.1 * max(magnitude(:));
                edges = magnitude > threshold;
                
                app.FilteredImages{end+1} = uint8(edges * 255);
                app.FilterNames{end+1} = 'Prewitt Kenar Algılama';
            end
                        
            % Morfolojik Filtreler - Min (Erosion'a benzer)
            if app.MinCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Min Filtre Uygulanıyor...');
                
                % Manuel min filtre
                grayImg = rgb2gray(app.OrijinalFotograf);
                [rows, cols] = size(grayImg);
                minFiltered = zeros(rows, cols);
                
                % 3x3 komşulukta minimum bulma
                for i = 2:rows-1
                    for j = 2:cols-1
                        window = grayImg(i-1:i+1, j-1:j+1);
                        minFiltered(i, j) = min(window(:));
                    end
                end
                
                % Kenar pikselleri aynı bırak
                minFiltered([1 end], :) = double(grayImg([1 end], :));
                minFiltered(:, [1 end]) = double(grayImg(:, [1 end]));
                
                app.FilteredImages{end+1} = uint8(minFiltered);
                app.FilterNames{end+1} = 'Min Filtre';
            end
            
            % Morfolojik Filtreler - Max (Dilation'a benzer)
            if app.MaxCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Max Filtre Uygulanıyor...');
                
                % Manuel max filtre
                grayImg = rgb2gray(app.OrijinalFotograf);
                [rows, cols] = size(grayImg);
                maxFiltered = zeros(rows, cols);
                
                % 3x3 komşulukta maksimum bulma
                for i = 2:rows-1
                    for j = 2:cols-1
                        window = grayImg(i-1:i+1, j-1:j+1);
                        maxFiltered(i, j) = max(window(:));
                    end
                end
                
                % Kenar pikselleri aynı bırak
                maxFiltered([1 end], :) = double(grayImg([1 end], :));
                maxFiltered(:, [1 end]) = double(grayImg(:, [1 end]));
                
                app.FilteredImages{end+1} = uint8(maxFiltered);
                app.FilterNames{end+1} = 'Max Filtre';
            end
            
            % Morfolojik Filtreler - Median
            if app.MedianCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Medyan Filtre Uygulanıyor...');
                
                % Manuel median filtre
                if size(app.OrijinalFotograf, 3) == 3
                    img = rgb2gray(app.OrijinalFotograf);
                else
                    img = app.OrijinalFotograf;
                end
                
                [rows, cols] = size(img);
                medianFiltered = zeros(rows, cols);
                
                % 3x3 komşulukta medyan bulma
                for i = 2:rows-1
                    for j = 2:cols-1
                        window = img(i-1:i+1, j-1:j+1);
                        medianFiltered(i, j) = median(window(:));
                    end
                end
                
                % Kenar pikselleri aynı bırak
                medianFiltered([1 end], :) = double(img([1 end], :));
                medianFiltered(:, [1 end]) = double(img(:, [1 end]));
                
                app.FilteredImages{end+1} = uint8(medianFiltered);
                app.FilterNames{end+1} = 'Medyan Filtre';
            end
            
            % Uzamsal Filtreler - High Boost (Keskinleştirme)
            if app.HighBoostCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'High Boost Filtre Uygulanıyor...');
                
                % Manuel high boost filtre
                img = double(app.OrijinalFotograf);
                
                % Laplacian kernel (keskinleştirme için)
                laplacianKernel = [0 -1 0; -1 5 -1; 0 -1 0];
                
                % Manuel konvolüsyon
                if size(img, 3) == 3
                    filtered = zeros(size(img));
                    for ch = 1:3
                        filtered(:,:,ch) = app.manualConv2D(img(:,:,ch), laplacianKernel);
                    end
                else
                    filtered = app.manualConv2D(img, laplacianKernel);
                end
                
                % Değerleri sınırla
                filtered = max(0, min(255, filtered));
                app.FilteredImages{end+1} = uint8(filtered);
                app.FilterNames{end+1} = 'High Boost Filtre';
            end
            
            % Uzamsal Filtreler - Weighted Average (Ağırlıklı Ortalama)
            if app.WeightedCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Ağırlıklı Ortalama Filtre Uygulanıyor...');
                
                % Gaussian-benzeri ağırlıklı kernel
                gaussKernel = [1 2 1; 2 4 2; 1 2 1] / 16;
                
                img = double(app.OrijinalFotograf);
                
                % Manuel konvolüsyon
                if size(img, 3) == 3
                    filtered = zeros(size(img));
                    for ch = 1:3
                        filtered(:,:,ch) = app.manualConv2D(img(:,:,ch), gaussKernel);
                    end
                else
                    filtered = app.manualConv2D(img, gaussKernel);
                end
                
                app.FilteredImages{end+1} = uint8(filtered);
                app.FilterNames{end+1} = 'Ağırlıklı Ortalama Filtre';
            end
            
            % Uzamsal Filtreler - Average (Ortalama)
            if app.AverageCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Ortalama Filtre Uygulanıyor...');
                
                % 3x3 ortalama kernel
                avgKernel = ones(3,3) / 9;
                
                img = double(app.OrijinalFotograf);
                
                % Manuel konvolüsyon
                if size(img, 3) == 3
                    filtered = zeros(size(img));
                    for ch = 1:3
                        filtered(:,:,ch) = app.manualConv2D(img(:,:,ch), avgKernel);
                    end
                else
                    filtered = app.manualConv2D(img, avgKernel);
                end
                
                app.FilteredImages{end+1} = uint8(filtered);
                app.FilterNames{end+1} = 'Ortalama Filtre';
            end
            
            % Gürültü Ekleme - Gaussian Noise
            if app.GaussianNoiseCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Gauss Gürültüsü Ekleniyor...');
                % Özel Gauss gürültüsü fonksiyonunu çağır
                app.FilteredImages{end+1} = app.addGaussianNoise(app.OrijinalFotograf, 0, 0.01); % Ortalama 0, varyans 0.01
                app.FilterNames{end+1} = 'Gauss Gürültüsü';
            end
            
            % Gürültü Ekleme - Impulse Noise (Salt & Pepper'a benzer)
            if app.ImpulseCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Darbe Gürültüsü Ekleniyor...');
                % Özel Tuz & Biber gürültüsü fonksiyonunu
                app.FilteredImages{end+1} = app.addSaltAndPepperNoise(app.OrijinalFotograf, 0.02); % Varsayılan yoğunluk 0.02
                app.FilterNames{end+1} = 'Darbe Gürültüsü (Tuz & Biber)';
            end

            % Gürültü Ekleme - Salt & Pepper Noise
            if app.SaltPepperCheckBox.Value && ~app.ImpulseCheckBox.Value % Impulse seçili değilse uygula
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Tuz & Biber Gürültüsü Ekleniyor...');
                density = app.SaltPaperfield.Value;
                if isempty(density) || density < 0 || density > 1
                    density = 0.02; % Varsayılan yoğunluk
                    uialert(app.PhotoFiltererUIFigure, 'Tuz & Biber Gürültüsü için geçerli bir yoğunluk (0-1 arası) girilmedi, varsayılan 0.02 kullanıldı.', 'Uyarı');
                end
                % Özel Tuz & Biber gürültüsü fonksiyonunu çağır (SaltPaperfield değeri ile)
                app.FilteredImages{end+1} = app.addSaltAndPepperNoise(app.OrijinalFotograf, density);
                app.FilterNames{end+1} = sprintf('Tuz & Biber Gürültüsü (Yoğunluk: %.2f)', density);
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - Dilation (Genişletme)
            if app.DilationCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Genişletme (Dilation) Uygulanıyor...');
                
                % Manuel disk şeklinde yapısal eleman (3x3 kare kullanıyoruz)
                se = ones(3, 3);
                
                % Renkli görüntüler için her kanala ayrı ayrı uygula
                if size(app.OrijinalFotograf, 3) == 3
                    dilatedR = app.manualDilation(app.OrijinalFotograf(:,:,1), se);
                    dilatedG = app.manualDilation(app.OrijinalFotograf(:,:,2), se);
                    dilatedB = app.manualDilation(app.OrijinalFotograf(:,:,3), se);
                    app.FilteredImages{end+1} = cat(3, dilatedR, dilatedG, dilatedB);
                else
                    app.FilteredImages{end+1} = app.manualDilation(app.OrijinalFotograf, se);
                end
                app.FilterNames{end+1} = 'Genişletme (Dilation)';
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - Erosion (Aşındırma)
            if app.ErasionCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Aşındırma (Erosion) Uygulanıyor...');
                
                % Manuel disk şeklinde yapısal eleman (3x3 kare kullanıyoruz)
                se = ones(3, 3);
                
                % Renkli görüntüler için her kanala ayrı ayrı uygula
                if size(app.OrijinalFotograf, 3) == 3
                    erodedR = app.manualErosion(app.OrijinalFotograf(:,:,1), se);
                    erodedG = app.manualErosion(app.OrijinalFotograf(:,:,2), se);
                    erodedB = app.manualErosion(app.OrijinalFotograf(:,:,3), se);
                    app.FilteredImages{end+1} = cat(3, erodedR, erodedG, erodedB);
                else
                    app.FilteredImages{end+1} = app.manualErosion(app.OrijinalFotograf, se);
                end
                app.FilterNames{end+1} = 'Aşındırma (Erosion)';
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - Derivative (Türev)
            if app.DerivativeCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Türev Uygulanıyor...');
                grayImg = rgb2gray(app.OrijinalFotograf);
                
                % Manuel Sobel operatörleri (fspecial olmadan)
                Gx = [-1 0 1; -2 0 2; -1 0 1]; % X yönü Sobel
                Gy = [-1 -2 -1; 0 0 0; 1 2 1]; % Y yönü Sobel
                
                % Manuel konvolüsyon (imfilter olmadan)
                Ix = app.manualConv2D(double(grayImg), Gx);
                Iy = app.manualConv2D(double(grayImg), Gy);
                
                % Magnitude hesaplama
                magnitude = sqrt(Ix.^2 + Iy.^2);
                
                % Normalizasyon
                normalizedMag = (magnitude - min(magnitude(:))) / (max(magnitude(:)) - min(magnitude(:)));
                app.FilteredImages{end+1} = uint8(normalizedMag * 255);
                app.FilterNames{end+1} = 'Türev (Kenar Yoğunluğu)';
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - nth power root (Gamma Düzeltme)
            if app.nthpowerrootCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'n. Dereceden Kök (Gamma) Uygulanıyor...');
                gammaValue = app.Nthfield.Value; % Nthfield'den gamma değerini al
                
                if isempty(gammaValue) || gammaValue <= 0
                    gammaValue = 0.5; % Varsayılan değer
                    uialert(app.PhotoFiltererUIFigure, 'n. Dereceden Kök için geçerli bir değer girilmedi, varsayılan 0.5 kullanıldı.', 'Uyarı');
                end
                
                % Manuel gamma düzeltme (imadjust olmadan)
                imgDouble = double(app.OrijinalFotograf) / 255; % 0-1 aralığına normalize et
                gammaAdjusted = imgDouble .^ gammaValue; % Gamma düzeltme uygula
                
                % Tekrar uint8'e çevir
                app.FilteredImages{end+1} = uint8(gammaAdjusted * 255);
                app.FilterNames{end+1} = sprintf('n. Dereceden Kök (Gamma: %.2f)', gammaValue);
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - Log / Reverse Log
            if app.LogReverseLogCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Log/Ters Log Dönüşümü Uygulanıyor...');
                
                % LogField'den değeri al
                logValue = str2double(app.Logfield.Value);
                if isnan(logValue) || logValue <= 0
                    logValue = 1; % Varsayılan değer
                end
                
                % Log dönüşümü
                imgDouble = double(app.OrijinalFotograf) / 255;
                logTransformed = log(1 + imgDouble * logValue);
                
                % Manuel normalize ve uint8'e çevir
                logNorm = (logTransformed - min(logTransformed(:))) / (max(logTransformed(:)) - min(logTransformed(:)));
                app.FilteredImages{end+1} = uint8(logNorm * 255);
                app.FilterNames{end+1} = 'Log Dönüşümü';
                
                % Ters Log dönüşümü
                reverseLogTransformed = exp(imgDouble * logValue) - 1;
                
                % Manuel normalize ve uint8'e çevir
                revLogNorm = (reverseLogTransformed - min(reverseLogTransformed(:))) / (max(reverseLogTransformed(:)) - min(reverseLogTransformed(:)));
                app.FilteredImages{end+1} = uint8(revLogNorm * 255);
                app.FilterNames{end+1} = 'Ters Log Dönüşümü';
                filterCount = filterCount + 1;
            end
            
            % Yoğunluk Dönüşüm Fonksiyonları - Negative (Negatif)
            if app.NegativeCheckBox.Value
                filterCount = filterCount + 1;
                app.updateProgressBar(filterCount, numSelectedFilters, 'Negatif Dönüşüm Uygulanıyor...');
                % Görüntü tipine göre maksimum değeri belirle
                if isinteger(app.OrijinalFotograf)
                    maxVal = intmax(class(app.OrijinalFotograf));
                else % double veya single gibi kayan noktalı tipler için
                    maxVal = 1.0;
                end
                app.FilteredImages{end+1} = maxVal - app.OrijinalFotograf;
                app.FilterNames{end+1} = 'Negatif';
            end
            
            % Tüm filtreler uygulandıktan sonra ilerleme çubuğunu gizle
            app.ProgressBarGauge.Visible = 'off';
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = ''; % Mesajı temizle
            
            % Yeni ekranı oluştur ve fotoğrafları göster
            app.ShowFilteredImages();
        end
             % Helper function to update the progress bar
        function updateProgressBar(app, currentFilterCount, totalFilters, message)
            progress = (currentFilterCount / totalFilters) * 100;
            app.ProgressBarGauge.Value = progress;
            app.ProgressBarLabel.Text = sprintf('%s (%.0f%%)', message, progress);
            drawnow; % Arayüzü hemen güncelle
        end
        
        % Filtrelenmiş görüntüleri yeni bir pencerede gösterme fonksiyonu
        function ShowFilteredImages(app)
            if isempty(app.FilteredImages)
                uialert(app.PhotoFiltererUIFigure, 'Filtrelenmiş fotoğraf bulunamadı.', 'Hata');
                return;
            end
            
            resultFigure = uifigure('Name', 'Filtrelenmiş Görüntüler', 'Position', [100 100 1200 800]);
            
            numImages = length(app.FilteredImages);
            
            if numImages <= 3
                numRows = 1;
                numCols = numImages;
            elseif numImages <= 6
                numRows = 2;
                numCols = ceil(numImages / 2);
            elseif numImages <= 9
                numRows = 3;
                numCols = ceil(numImages / 3);
            else
                numRows = ceil(sqrt(numImages));
                numCols = ceil(numImages / numRows);
            end
            
            tiledLayout = uigridlayout(resultFigure, [numRows numCols]);
            tiledLayout.Padding = 0; % Düzeltme: 'compact' yerine 0 kullanıldı
            tiledLayout.RowHeight = {'1x'}; % Düzeltme: Tek '1x' yeterli
            tiledLayout.ColumnWidth = {'1x'}; % Düzeltme: Tek '1x' yeterli
            
            for i = 1:numImages
                ax = uiaxes(tiledLayout);
                ax.Layout.Row = ceil(i / numCols);
                ax.Layout.Column = mod(i - 1, numCols) + 1;
                
                imshow(app.FilteredImages{i}, 'Parent', ax);
                title(ax, app.FilterNames{i});
                
                ax.XAxis.Visible = 'off';
                ax.YAxis.Visible = 'off';
            end
        end

        % Özel fonksiyon: Görüntüye Gauss gürültüsü ekler
        function noisyImage = addGaussianNoise(~, image, mean, variance)
            % Görüntüyü hesaplamalar için double tipine dönüştür
            imgDouble = im2double(image);
            
            % Gauss gürültüsü oluştur
            noise = mean + sqrt(variance) * randn(size(imgDouble));
            
            % Gürültüyü görüntüye ekle
            noisyImage = imgDouble + noise;
            
            % Değerleri [0, 1] aralığına kırp (double görüntüler için)
            noisyImage = max(0, min(1, noisyImage));
            
            % Gerekirse orijinal görüntü sınıfına geri dönüştür
            if isinteger(image)
                noisyImage = uint8(max(0, min(1, noisyImage)) * 255);
            end
        end

        % Özel fonksiyon: Görüntüye Tuz & Biber gürültüsü ekler
        function noisyImage = addSaltAndPepperNoise(~, image, density)
            % Görüntüyü hesaplama için double tipine dönüştür (0-1 aralığı)
            imgDouble = im2double(image);
            
            % Değiştirmek için bir kopya oluştur
            noisyImage = imgDouble;
            
            % Toplam piksel sayısını belirle
            numPixels = numel(imgDouble);
            
            % Gürültülü piksel sayısını hesapla
            numNoisyPixels = round(density * numPixels);
            
            % Tuz gürültüsü için rastgele pikseller seç
            saltPixels = randperm(numPixels, round(numNoisyPixels / 2));
            noisyImage(saltPixels) = 1; % Beyaz yap (double için maksimum değer)
            
            % Biber gürültüsü için rastgele pikseller seç
            pepperPixels = randperm(numPixels, round(numNoisyPixels / 2));
            noisyImage(pepperPixels) = 0; % Siyah yap (double için minimum değer)
            
            % Gerekirse orijinal görüntü sınıfına geri dönüştür
            if isinteger(image)
                noisyImage = uint8(max(0, min(1, noisyImage)) * 255);
            end
        end
        % manualConv2D yardımcı fonksiyon
        function result = manualConv2D(app, image, kernel)
            [rows, cols] = size(image);
            [kRows, kCols] = size(kernel);
            
            padR = floor(kRows/2);
            padC = floor(kCols/2);
            
            % Manuel padding (padarray olmadan)
            paddedImg = zeros(rows + 2*padR, cols + 2*padC);
            
            % Orijinal görüntüyü ortaya yerleştir
            paddedImg(padR+1:padR+rows, padC+1:padC+cols) = image;
            
            % Kenarları replicate et
            % Üst ve alt kenarlar
            for i = 1:padR
                paddedImg(i, padC+1:padC+cols) = image(1, :); % Üst kenar
                paddedImg(rows+padR+i, padC+1:padC+cols) = image(rows, :); % Alt kenar
            end
            
            % Sol ve sağ kenarlar
            for j = 1:padC
                paddedImg(padR+1:padR+rows, j) = image(:, 1); % Sol kenar
                paddedImg(padR+1:padR+rows, cols+padC+j) = image(:, cols); % Sağ kenar
            end
            
            % Köşeler
            paddedImg(1:padR, 1:padC) = image(1, 1); % Sol üst köşe
            paddedImg(1:padR, cols+padC+1:end) = image(1, cols); % Sağ üst köşe
            paddedImg(rows+padR+1:end, 1:padC) = image(rows, 1); % Sol alt köşe
            paddedImg(rows+padR+1:end, cols+padC+1:end) = image(rows, cols); % Sağ alt köşe
            
            result = zeros(rows, cols);
            
            % Konvolüsyon işlemi
            for i = 1:rows
                for j = 1:cols
                    window = paddedImg(i:i+kRows-1, j:j+kCols-1);
                    result(i, j) = sum(sum(window .* kernel));
                end
            end
        end
        % Manuel Dilation fonksiyonu
        function result = manualDilation(app, image, se)
            [rows, cols] = size(image);
            [seRows, seCols] = size(se);
            
            padR = floor(seRows/2);
            padC = floor(seCols/2);
            
            % Manuel padding
            paddedImg = zeros(rows + 2*padR, cols + 2*padC);
            paddedImg(padR+1:padR+rows, padC+1:padC+cols) = double(image);
            
            % Kenarları replicate et
            for i = 1:padR
                paddedImg(i, padC+1:padC+cols) = double(image(1, :));
                paddedImg(rows+padR+i, padC+1:padC+cols) = double(image(rows, :));
            end
            for j = 1:padC
                paddedImg(padR+1:padR+rows, j) = double(image(:, 1));
                paddedImg(padR+1:padR+rows, cols+padC+j) = double(image(:, cols));
            end
            
            result = zeros(rows, cols);
            
            % Dilation işlemi (maksimum değer)
            for i = 1:rows
                for j = 1:cols
                    window = paddedImg(i:i+seRows-1, j:j+seCols-1);
                    maskedWindow = window(se == 1);
                    result(i, j) = max(maskedWindow(:));
                end
            end
            
            result = uint8(result);
        end
        
        % Manuel Erosion fonksiyonu
        function result = manualErosion(~, image, se)
            [rows, cols] = size(image);
            [seRows, seCols] = size(se);
            
            padR = floor(seRows/2);
            padC = floor(seCols/2);
            
            % Manuel padding
            paddedImg = ones(rows + 2*padR, cols + 2*padC) * 255; % Erosion için 255 ile pad
            paddedImg(padR+1:padR+rows, padC+1:padC+cols) = double(image);
            
            result = zeros(rows, cols);
            
            % Erosion işlemi (minimum değer)
            for i = 1:rows
                for j = 1:cols
                    window = paddedImg(i:i+seRows-1, j:j+seCols-1);
                    maskedWindow = window(se == 1);
                    result(i, j) = min(maskedWindow(:));
                end
            end
            result = uint8(result);
        end
        % Manuel OTSU threshold hesaplama (imhist olmadan)
        function threshold = manualOtsuThreshold(app, image)
            % Görüntüyü uint8 formatına çevir (eğer değilse)
            if ~isa(image, 'uint8')
                image = uint8(image);
            end
            
            % Histogram hesaplama (imhist yerine)
            counts = zeros(256, 1);
            for i = 1:numel(image)
                pixel_value = image(i) + 1; % MATLAB 1-indexing
                counts(pixel_value) = counts(pixel_value) + 1;
            end
            
            % Bins oluştur (0-255)
            bins = 0:255;
            
            total = sum(counts);
            
            % Normalize histogram
            probabilities = counts / total;
            
            % Otsu algoritması
            maxVariance = 0;
            threshold = 0;
            
            for t = 1:256
                % Class probabilities
                w0 = sum(probabilities(1:t));
                w1 = sum(probabilities(t+1:end));
                
                if w0 == 0 || w1 == 0
                    continue;
                end
                
                % Class means
                mu0 = sum((0:t-1) .* probabilities(1:t)') / w0;
                mu1 = sum((t:255) .* probabilities(t+1:end)') / w1;
                
                % Between class variance
                variance = w0 * w1 * (mu0 - mu1)^2;
                
                if variance > maxVariance
                    maxVariance = variance;
                    threshold = t - 1; % 0-255 aralığına çevir
                end
            end
        end
        
        % Alternatif olarak, daha verimli histogram hesaplama:
        function threshold = manualOtsuThresholdFast(app, image)
            % Görüntüyü uint8 formatına çevir (eğer değilse)
            if ~isa(image, 'uint8')
                image = uint8(image);
            end
            
            % Hızlı histogram hesaplama
            counts = histcounts(image(:), 0:256);
            total = sum(counts);
            
            % Normalize histogram
            probabilities = counts / total;
            
            % Otsu algoritması
            maxVariance = 0;
            threshold = 0;
            
            for t = 1:256
                % Class probabilities
                w0 = sum(probabilities(1:t));
                w1 = sum(probabilities(t+1:end));
                
                if w0 == 0 || w1 == 0
                    continue;
                end
                
                % Class means
                mu0 = sum((0:t-1) .* probabilities(1:t)) / w0;
                mu1 = sum((t:255) .* probabilities(t+1:end)) / w1;
                
                % Between class variance
                variance = w0 * w1 * (mu0 - mu1)^2;
                
                if variance > maxVariance
                    maxVariance = variance;
                    threshold = t - 1; % 0-255 aralığına çevir
                end
            end
        end
        
        % Basitleştirilmiş Harris köşe algılama
        function corners = manualHarrisCorners(app, image)
            % Gradientler
            Ix = app.manualConv2D(double(image), [-1 0 1; -2 0 2; -1 0 1]);
            Iy = app.manualConv2D(double(image), [-1 -2 -1; 0 0 0; 1 2 1]);
            
            % Harris matrix bileşenleri
            Ixx = Ix .* Ix;
            Iyy = Iy .* Iy;
            Ixy = Ix .* Iy;
            
            % Gaussian window
            gaussKernel = [1 2 1; 2 4 2; 1 2 1] / 16;
            
            Sxx = app.manualConv2D(Ixx, gaussKernel);
            Syy = app.manualConv2D(Iyy, gaussKernel);
            Sxy = app.manualConv2D(Ixy, gaussKernel);
            
            % Harris response
            k = 0.04;
            det = Sxx .* Syy - Sxy .* Sxy;
            trace = Sxx + Syy;
            R = det - k * trace.^2;
            
            % Find local maxima
            threshold = 0.01 * max(R(:));
            [rows, cols] = size(R);
            corners = [];
            
            for i = 2:rows-1
                for j = 2:cols-1
                    if R(i,j) > threshold
                        window = R(i-1:i+1, j-1:j+1);
                        if R(i,j) == max(window(:))
                            corners = [corners; j, i];
                        end
                    end
                end
            end
            
            % Limit corner count
            if size(corners, 1) > 100
                corners = corners(1:100, :);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhotoFiltererUIFigure = uifigure('Visible', 'off');
            app.PhotoFiltererUIFigure.Name = 'PhotoFilterer';
            app.UploadaPhotoButton = uibutton(app.PhotoFiltererUIFigure, 'push');
            app.UploadaPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadaPhotoButtonPushed, true);
            app.UploadaPhotoButton.Text = 'Upload a Photo';
            app.Image = uiimage(app.PhotoFiltererUIFigure);
            app.ApplytheFiltersButton = uibutton(app.PhotoFiltererUIFigure, 'push');
            app.ApplytheFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytheFiltersButtonPushed, true);
            app.ApplytheFiltersButton.Text = 'Apply the Filters';
            app.ProgressBarLabel = uilabel(app.PhotoFiltererUIFigure);
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = 'Progress Bar';
            app.ProgressBarGauge = uigauge(app.PhotoFiltererUIFigure, 'semicircular');
            app.ProgressBarGauge.Visible = 'off';
            app.NegativeCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.NegativeCheckBox.Text = 'Negative';
            app.LogReverseLogCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.LogReverseLogCheckBox.Text = 'Log/Reverse Log';
            app.nthpowerrootCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.nthpowerrootCheckBox.Text = 'n''th power/root';
            app.DerivativeCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.DerivativeCheckBox.Text = 'Derivative';
            app.ErasionCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.ErasionCheckBox.Text = 'Erasion';
            app.DilationCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.DilationCheckBox.Text = 'Dilation';
            app.IntensityTransformationFunctionsLabel = uilabel(app.PhotoFiltererUIFigure);
            app.IntensityTransformationFunctionsLabel.Text = 'Intensity Transformation Functions';
            app.Logfield = uieditfield(app.PhotoFiltererUIFigure, 'numeric');
            app.Nthfield = uieditfield(app.PhotoFiltererUIFigure, 'numeric');
            app.Nthfield.Limits = [0 1];
            app.SaltPepperCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.SaltPepperCheckBox.Text = 'Salt & Pepper';
            app.ImpulseCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.ImpulseCheckBox.Text = 'Impulse';
            app.GaussianNoiseCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.GaussianNoiseCheckBox.Text = 'Gaussian Noise';
            app.NoiseLabel = uilabel(app.PhotoFiltererUIFigure);
            app.NoiseLabel.Text = 'Noise';
            app.AverageCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.AverageCheckBox.Text = 'Average';
            app.WeightedCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.WeightedCheckBox.Text = 'Weighted';
            app.HighBoostCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.HighBoostCheckBox.Text = 'High-Boost';
            app.SpatialFiltersLabel = uilabel(app.PhotoFiltererUIFigure);
            app.SpatialFiltersLabel.Text = 'Spatial Filters';
            app.MedianCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.MedianCheckBox.Text = 'Median';
            app.MaxCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.MaxCheckBox.Text = 'Max';
            app.MinCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.MinCheckBox.Text = 'Min';
            app.MorphologyLabel = uilabel(app.PhotoFiltererUIFigure);
            app.MorphologyLabel.Text = 'Morphology';
            app.PrewittCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.PrewittCheckBox.Text = 'Prewitt';
            app.CannyCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.CannyCheckBox.Text = 'Canny';
            app.EdgeDetectionLabel = uilabel(app.PhotoFiltererUIFigure);
            app.EdgeDetectionLabel.Text = 'Edge Detection';
            app.SobelCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.SobelCheckBox.Text = 'Sobel';
            app.RobertsCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.RobertsCheckBox.Text = 'Roberts';
            app.HarrisCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.HarrisCheckBox.Text = 'Harris ';
            app.CornerDetectionLabel = uilabel(app.PhotoFiltererUIFigure);
            app.CornerDetectionLabel.Text = 'Corner Detection';
            app.OTSUCheckBox = uicheckbox(app.PhotoFiltererUIFigure);
            app.OTSUCheckBox.Text = 'OTSU';
            app.OTSUSegmentationLabel = uilabel(app.PhotoFiltererUIFigure);
            app.OTSUSegmentationLabel.Text = 'OTSU Segmentation';
            app.HighBoostfield = uieditfield(app.PhotoFiltererUIFigure, 'numeric');
            app.SaltPaperfield = uieditfield(app.PhotoFiltererUIFigure, 'numeric');
            app.Image2 = uiimage(app.PhotoFiltererUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            % Show the figure after all components are created
            app.PhotoFiltererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhotoFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhotoFiltererUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhotoFiltererUIFigure)
        end
    end
end